classdef ToRThing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab1                           matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        OpenFileButtontAs              matlab.ui.control.Button
        TabGroup2                      matlab.ui.container.TabGroup
        S_ITab                         matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        D_ITab                         matlab.ui.container.Tab
        UIAxes1                        matlab.ui.control.UIAxes
        FG_STTab                       matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        control_feedback_errorTab      matlab.ui.container.Tab
        UIAxes4                        matlab.ui.control.UIAxes
        CapMatrix                      matlab.ui.control.Table
        ShowLinesCheckBox              matlab.ui.control.CheckBox
        ShiftAmountSpinnerLabel        matlab.ui.control.Label
        ShiftAmountSpinner             matlab.ui.control.Spinner
        UITable5                       matlab.ui.control.Table
        GenerateLinesButton            matlab.ui.control.Button
        SETChargingEnergymeVEditFieldLabel  matlab.ui.control.Label
        SETChargingEnergymeVEditField  matlab.ui.control.NumericEditField
        SETLeverArmLabel               matlab.ui.control.Label
        SETLeverArmEditField           matlab.ui.control.NumericEditField
        DOTLeverArmLabel               matlab.ui.control.Label
        DOTLeverArmEditField           matlab.ui.control.NumericEditField
        DOTChargingEnergymeVEditFieldLabel  matlab.ui.control.Label
        DOTChargingEnergymeVEditField  matlab.ui.control.NumericEditField
        CapacitanceMatrixaFLabel       matlab.ui.control.Label
        SaveCurrentDataButton          matlab.ui.control.Button
        Vg1Label                       matlab.ui.control.Label
        Vg2Label                       matlab.ui.control.Label
        Vg2Label_2                     matlab.ui.control.Label
        Vg1Label_2                     matlab.ui.control.Label
        VtgsLabel                      matlab.ui.control.Label
        VLabel                         matlab.ui.control.Label
        VLabel_2                       matlab.ui.control.Label
        VLabel_3                       matlab.ui.control.Label
        Tab2                           matlab.ui.container.Tab
        LinesDropDownLabel             matlab.ui.control.Label
        LinesDropDown                  matlab.ui.control.DropDown
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes7                        matlab.ui.control.UIAxes
        UITable3                       matlab.ui.control.Table
        Label_4                        matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        UITable4                       matlab.ui.control.Table
        PreviousLineButton             matlab.ui.control.Button
        NextLineButton                 matlab.ui.control.Button
        FullLinesTab                   matlab.ui.container.Tab
        UIAxMatrix                     matlab.ui.control.UIAxes
        XSlice                         matlab.ui.control.UIAxes
        YSlice                         matlab.ui.control.UIAxes
        Label_5                        matlab.ui.control.Label
        YSlider                        matlab.ui.control.Slider
        Label_6                        matlab.ui.control.Label
        XSlider                        matlab.ui.control.Slider
        MatrixDropDownLabel            matlab.ui.control.Label
        MatrixDropDown                 matlab.ui.control.DropDown
        ProcessesTab                   matlab.ui.container.Tab
        UIAxes5                        matlab.ui.control.UIAxes
        Label_3                        matlab.ui.control.Label
        Spinner                        matlab.ui.control.Spinner
    end


    properties (Access = private)
        Path
        File % Description
        Obj


        LineEquations 

        Offset 
        MatrixShift = 10 % is nuetral = no shift
        MaxShift = 10 %i.e. can shift up or down by x
        Matrix %struct containing matrix1 (I_D), matrix2(S_D), matrix3 (FG_ST), matrix4 (control_feedback_error)
        MatrixSize 
        %image stuff from generateLines
        Im
       
        Lines %cell array holding the lines
        numLines 
        LinePlotId
        
        BlkMCell % Description
        CurrentLine = 1 % Description
        Capacitance % Description
        LineTableData % array for the main table
        Property18 % Description
    end

    methods (Access = public)
          
        function matrix = appGenerateMatrix(app)
            obj = app.Obj;
            x = obj.Data.G_G1;
            y = obj.Data.G_G2;
            
            
            matrix1 = AutoMatrix(x, y, obj.Data.S_I);
            matrix2 = AutoMatrix(x, y, obj.Data.D_I);
            app.Matrix.Matrix1{app.MatrixShift} = appShiftMatrix(app, matrix1);
            app.Matrix.Matrix2{app.MatrixShift} = appShiftMatrix(app, matrix2);
            
            
            %can probably ignore these  for now
            matrix3 = AutoMatrix(x, y, obj.Data.FG_ST);
            matrix4 = AutoMatrix(x, y, obj.Data.control_feedback_error);
            app.Matrix.Matrix3{app.MatrixShift} = appShiftMatrix(app, matrix3);
            app.Matrix.Matrix4{app.MatrixShift} = appShiftMatrix(app, matrix4);

            matrix = app.Matrix.Matrix1{app.MatrixShift};
            app.Obj.Matrix = app.Matrix.Matrix1{app.MatrixShift};
            
            app.MatrixSize.xmin = matrix1.xmin;
            app.MatrixSize.xmax = matrix1.xmax; 
            app.MatrixSize.ymin = matrix1.ymin; 
            app.MatrixSize.ymax = matrix1.ymax;  
        end
        
        function matrix = appShiftMatrix(app, matrix)
%             shiftM = obj.MatrixShift;
            % Reshift D_I a bit so doesnt create zigzag effect, also remove the first row since its imcomplete data
            shiftAmt = app.MatrixShift - app.MaxShift;
            D_I = matrix;

            if (isstruct(matrix)) 
                D_I = D_I.m(2:1:end,:)-mean(D_I.m(2:1:end,:),2);
            else 
                D_I = D_I(2:1:end,:)-mean(D_I(2:1:end,:),2);
            end
            
            D_I1 = D_I(1:2:end,:);
            D_I2 = D_I(2:2:end,:);

            xc1 = xcorr2(D_I1, D_I2);
            
            [~,ix1]= max(xc1(size(D_I1,1)+0,:));
            [~,ix2]= max(xc1(size(D_I1,1)+0,:));
            shift = round((ix2-ix1)/2 + shiftAmt); 
            D_I = shiftMatrix(D_I, 1*shiftAmt*(-1).^(1:size(D_I,1)));
            
            matrix = D_I;
            app.Obj.Matrix = D_I;
        end
        
        function obj = appExtractStuff(app, filename, shift)
           obj.File = filename;
           obj.MatrixShift = shift;
           
            addpath('tools');
            S = load(filename);
            obj.Data = S.meas.data{1,1}.rows;
            obj.File = filename;
            obj.Matrix{app.MatrixShift} = [];
%             obj = runAll(obj);
%             obj.Matrix = ExtractStuff.generateMatrix();
        end
        
        
        function appPlotAllM(app)
            i = app.MatrixShift;
            appPlotMatrix(app, app.Matrix.Matrix1{i}, app.UIAxes1, 'D_I', 0)
            appPlotMatrix(app, app.Matrix.Matrix2{i}, app.UIAxes2, 'S_I', 0)
            appPlotMatrix(app, app.Matrix.Matrix3{i}, app.UIAxes3, 'FG_ST', 0)
            appPlotMatrix(app, app.Matrix.Matrix4{i}, app.UIAxes4, 'control_feedback_error', 0)
        end
       
        % Plots given image on a uiaxes
        % - matrix: can be an image or actual matrix. plots in 2D
        % - axes: specifies which uiaxes to plot in
        % - string: the title of the axes
        % - isImage: if it is an image (set to 1), then app will use imshow and have no axis labesl
        function appPlotMatrix(app,matrix ,axes, string, isImage, varargin)
%             matrix = app.Obj.Matrix;
%             matrix = app.Matrix.Matrix1
            obj = app.Obj;
            x = obj.Data.G_G1;
            y = obj.Data.G_G2;
            % Fill figure with axes and remove tick labels
%             app.UIAxes1.Position = [0 0 app.UIFigure.Position(3:4)];
            % Remove title, axis labels, and tick labels
            title(axes, []);
            xlabel(axes, []);
            ylabel(axes, []);
%             app.UIAxes1.XAxis.TickLabels = {}; 
%             app.UIAxes1.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            
            if (isImage)
                I = imshow(matrix,...
                'Parent', axes, ...
                'XData', [1 axes.Position(3)], ...
                'YData', [1 axes.Position(4)]);
            else 
                I = imagesc([min(x) max(x)],[min(y) max(y)], matrix,...
                    'Parent', axes, ...
                    'XData', [1 axes.Position(3)], ...
                    'YData', [1 axes.Position(4)]);
%                     I = imagesc([min(x) max(x)],[min(y) max(y)], matrix, 'Parent', axes,  'InitialMagnification', fit);
                
                    xlabel([obj.Data.Properties.VariableNames(1) '(0.2 V)'],'Interpreter', 'none','Parent', axes)
                    ylabel([obj.Data.Properties.VariableNames(2), '(0.2 V)'],'Interpreter', 'none','Parent', axes)
            end

            title(string,'Interpreter', 'none','Parent', axes)

            % Set limits of axes
            axes.XLim = [0 I.XData(2)];
            axes.YLim = [0 I.YData(2)];
            
            %set axis ticks
            axes.XTick = 0: I.XData(2)/8 : I.XData(2); %change middle # to have more or less intervals
            axes.YTick = 0: I.YData(2)/5 : I.YData(2);
            axes.XTickLabel = min(x):range(x)/8:max(x);
            axes.YTickLabel = min(y):range(y)/5:max(y);


        end
        
        function updateAll(app)
            %create empty cells for isempty check function
            app.Matrix.Matrix1{app.MaxShift*2} = [];
            app.Lines{app.MaxShift*2} = [];
            app.Capacitance{app.MaxShift*2} = [];
            app.LineTableData{app.MaxShift*2} = [];
            
            f = waitbar(0,'Please wait, Loading data...');
            app.Obj = appExtractStuff(app,app.File, 0);
            
            waitbar(.1,f,'Generating Matrix');
            app.Obj.DataSet =  'D_I';   %column in data. S_I, D_I etc
            app.Obj.Matrix = appGenerateMatrix(app);
            
            waitbar(.2,f,'Plotting Data data');
            appPlotAllM(app)
            
            waitbar(.3,f,'Generating Lines');
            generateLines(app)
            
            waitbar(.4,f,'Loading your data');
            generateOffsets(app)
            
            waitbar(.5,f,'Loading your data');
            appPlotBlocks(app, app.BlkMCell{1})
            
            waitbar(.99,f,'Loading your data');
%             mainHomeTable(app)
%             app.ShowLinesCheckBox.value = 0;
            close(f)

            updateMainLineTable(app, app.UITable5);
            updateFullLineTab(app)
            
            updateMainCapMatrix(app);
        end
        
        
        %=====================================================================================
        function generateLines(app)
            tic
            obj = app.Matrix.Matrix2{app.MatrixShift};
            xScale = 600;
            yScale = 500;
            im = mat2gray(app.Matrix.Matrix2{app.MatrixShift});
            im = imresize(im, [yScale, xScale]); % x = 600 (columns), y = 500 (rows)
            
            %create edge array
            im = imcomplement(im); 
            E = edge(im, 'sobel');
            
            %dilate and then thin to merge similar lines that are close together
            dilatedImage = imdilate(E,strel('disk',6));
            thinedImage = bwmorph(dilatedImage,'thin',inf);
                        
            %remove branches and whatnot
%             filteredIm = thinedImage - bwmorph(thinedImage, 'spur', Inf);
            
            %try remove branches?https://au.mathworks.com/matlabcentral/answers/292215-how-does-bwmorph-define-a-branch-point
            bp=bwlookup(thinedImage,  makelut(@(x) sum(x(:))>=4 & x(5)==1,3)); %branch points?
            filteredIm=thinedImage&~bp;
            
            %try to remove small regions
            % area = regionprops(thinedImage, 'Area');
            % areaSorted = sort([S.Area]);
            filteredIm2 = bwareaopen(filteredIm, 50); 
            
            %dilate and then thin to merge similar lines together
            dilatedImage2 = imdilate(filteredIm2,strel('disk',4));
            thinedImage2 = bwmorph(dilatedImage2,'thin',inf);
            
            %plot in processes tab
            appPlotMatrix(app, im, app.UIAxes5, 'original', 1)
            %save everything
            app.Im.Orig = im;
            app.Im.E = E;
            app.Im.Dilated = dilatedImage;
            app.Im.Thined = thinedImage;
            app.Im.Filtered = filteredIm;
            app.Im.Filtered2 = filteredIm2;
            app.Im.Dilated2 = dilatedImage2;
            app.Im.Thined2 = thinedImage2; 
            
            toc, tic
            %link similar edges
            [edgelist, labelededgeim] = edgelink(thinedImage2, 1);
            app.Im.Label = labelededgeim;
            
            %Form straight line segements from an edge list
            % seglist - a list of lines joined by a tag
            seglist = lineseg(edgelist, 2);
            toc, tic
            %--------------------------------------------------------------
            % resize back to original
            im2 = mat2gray(app.Matrix.Matrix2{app.MatrixShift});
            im = imresize(im, [size(im2)]); %  y = 500 (rows), x = 600 (columns),
            % convert to back to lines
            warning off
            cshift = app.MatrixShift; %current shift
            
            % index for new list
            j = 1;
            
            for i = 1:length(seglist)
                
                if size(seglist{i},1) > 2
                    % resize lines back to original
                    % setglist2 - the new list of rescaled edges with first column as y vals, 2nd column as x vals
                    r = seglist{i}(:,1)*size(im2, 1)/yScale;
                    c = seglist{i}(:,2)*size(im2, 2)/xScale + 5; %shift right a bit cuz
    
    %                 c = seglist2{i}(:,2); % x values
    %                 r = seglist2{i}(:,1); % y vals
                    
                    %extract the line equation and variables
                    [f, ~, ~] = fit(c, r, 'poly1');
                    % columns indicate which line is which, rows are the offset TODO?
                    linePoly{cshift,j} = f;
                    % Cell indicates the Offset TODO, change thefirst cell number\
                    % inside the cell, the first row is p1 (gradient), 2nd row is p2 (intercept)
                    linePolyCoef{cshift,1}(:,j) = [f.p1, f.p2];
                
                    % basically create 2d array for lines to plot. 
                    % The arrayfun basically solves for y given an x value
                    lineArr{j}(:,1) = [1:size(im,1)];
                    lineArr{j}(:,2) = arrayfun(@(y)fzero( @(x)f(x)-y,1),[1:size(im,1)]);
                    j = j + 1;
                end
            end
            
            %store 
            app.Lines{app.MatrixShift}.pts = lineArr;
            app.Lines{app.MatrixShift}.coef = linePolyCoef;
            app.numLines{app.MatrixShift} = length(lineArr);
            toc
        end
        %=====================================================================================

    end

    methods (Access = private)
    
        function results = plotLines(app, ax)
%             ax = app.UIAxes2;
            hold(ax, 'on');
            if ~isempty(app.Lines{app.MatrixShift})
                for i = 1:app.numLines{app.MatrixShift}
                    
                    %rescale lines to fit in dumb axes
                    x = app.Lines{app.MatrixShift}.pts{i}(:,2)*ax.XLim(2)/size(app.Matrix.Matrix2{app.MatrixShift},2);
                    y = app.Lines{app.MatrixShift}.pts{i}(:,1)*ax.YLim(2)/size(app.Matrix.Matrix2{app.MatrixShift},1);
                    
                    app.LinePlotId{end+1} = plot(ax, x, y, 'LineWidth', 2, 'Color', 'b');
                    set(app.LinePlotId{end},'Tag','Line')
                end
            end

        end
        
        function results = hideLines(app, ax)
            
            %make line visible or invisible
            %set(findobj('Tag','Line'),'LineStyle','none'); %doesnt work for some reason
            for i = 1:length(app.LinePlotId)
                set(app.LinePlotId{i},'LineStyle','none');
                app.LinePlotId{i} = [];
            end

        end
        
        function updateMainLineTable(app, ax)
           if isempty(app.LineTableData{app.MatrixShift}) && ~isempty(app.Lines{app.MatrixShift})
               line = app.Lines{app.MatrixShift}.coef;
               shift = app.MatrixShift;
               lineNumber = [1:app.numLines{app.MatrixShift}]';
               xmin = app.MatrixSize.xmin;
               xmax = app.MatrixSize.xmax;
               ymin = app.MatrixSize.ymin;
               ymax = app.MatrixSize.ymax;           
               
               xmaxOLD = size(app.Matrix.Matrix1{app.MatrixShift}, 2);
               ymaxOLD = size(app.Matrix.Matrix1{app.MatrixShift}, 1);


               gradient = line{shift,1}(1,:)' .* ((ymax-ymin)/(xmax-xmin)*(xmaxOLD/ymaxOLD));
               yPt = line{shift,1}(2,:)' .* (ymax- ymin)/ymaxOLD + ymin; %point on the edge of the plot, NOT actually the y int yet
               xPt = xmin;
               
               %get y intercept using above. rearange from , m = (y1-y2)/(x1-x2)
               yInt = -gradient * xPt + yPt;
               
               xInt = -yInt./gradient;
               
               tableData = [lineNumber,gradient, yInt, xInt];
               app.LineTableData{app.MatrixShift} = tableData;
           else
               tableData = app.LineTableData{app.MatrixShift};
           end
               app.UITable5.Data = tableData;
    %            app.UITable5.ColumnName = {'Row','Offset', 'Y-Intercept 1', 'Y-Intercept 2', 'Detected Position'};
               app.UITable5.ColumnWidth = {60, 'auto'};
        end
        % =====================================================================================
        % extract the area surrounding each line and then run analysis on that area

        function generateOffsets(app)
%             obj.MatrixShift = 0; %delete?
%             obj.DataSet = 'FG_ST';
            lineArr = app.Lines{app.MatrixShift}.pts;
            skel = app.Matrix.Matrix3{app.MatrixShift};
            blksz = [1 95];
            blkM = [];
            blkMCell = [];
            
            %--------------------------------------------------------------------------------
            % iterate through all the lines and extract surrounding block
            for m = 1: app.numLines{app.MatrixShift}
                blkM = [];
                %Find the skeleton pixels' index
                row = round(lineArr{m}(:,1));
                col = round(lineArr{m}(:,2));
                npts      = numel(col);
                
                %Pad the array and offset the rows/cols so every local block fits
                padAmount = floor(blksz./2); %distance from center to edge of block
                skelPad   = padarray(skel,padAmount); %We need to pad so that image boundary pixels are contained in a block
                
                %Some parameters
                %-Bottom of block will be the same as center before pad
                %-Top will be bottom + block size - 1 (inclusive    
                rowHigh = row+blksz(1)-1; 
                colHigh = col+blksz(2)-1;
                center  = padAmount+1; %Center of small block
                
                %Now the engine, we will loop over the image, create each local block
                %based on the previous line function
                
                %Start the engine!
%                 figure, hold on;
                for ii = 1:npts
                    %Extract small block
                    
                    if col(ii) < 1
                        msgbox('smthing wrong')
%                         col(ii) = 1;
                        break;
                    end
                    block = skelPad(row(ii):rowHigh(ii),col(ii):min(colHigh(ii), size(skelPad, 2)));
                    
                    block = [block zeros(1, blksz(end)-size(block,2))];
                    
                    %replace zeroes in block with adjacent number (for first and last
                    %lines)
                    a = find(block == 0);
                    if ismember(1, a)                   %check if padded 0 is at beginning
                        if ~ismember(length(block),a)
                            block(a) = block(a(end)+1);
                        end
                    elseif ismember(length(block),a)    %check if padded 0 is at end
                        block(a) = block(a(1)-1);
                    end
                    
                    blkM = [blkM; block];
%                     plot(block(center(1), :));
                end
                
                if col(ii) < 1
%                         col(ii) = 1;
                    break;
                end
                
                blkMCell{m} = blkM;
                app.BlkMCell{m} = blkM;

            end
            
%             plotPeaks(obj,1, blkMCell{1});
            
                
            % ----------------------------------------------------------------------------
            p1 = [0 0]; %variables for line equations
            p2 = [0 0];
            offset = [];

            %iterate through all the lines
            for m = 1: size(blkMCell,2)
                        Z = blkMCell{m};
                        %sweeping DOWN the array of pts
                        for x = 1:size(Z,1)
                                % get most common gradient of one horizonal sweep
                                grad = gradient(Z(x,:));
            %                     %a = mode(gradient(blkM(x,:)),2);
            %                     %some black magic to make sure its non zero                    
            %                     [n,bin] = hist(grad,unique(grad));
            %                     [~,idx] = sort(-n);
            %                     % n(idx) % count instances
            %                     bin(idx) % corresponding values
            %                     a = bin(find(bin(idx),1))
                                
                                %hard coded yes
                                a = -0.0010;
                                
                                %find a point left of the line AND witht the same gradient
                                %basically check points are consecutive
                                %basically sweeping left along x axis, starting at line
                                %pts is first consectutive line, pts2 is 2nd line
                                pts = [];
                                pts2 = [];
            %                     find(index == round(x(n)));
                                sizeLine = 10; %obj.OffsetLineThreshold;
                                count = 0;
                                i = 0;
            
                                %Move cursor to start of the plot
                                start = find(Z(x,:),1);
                                
                                %now sweep right to find common line with common
                                %gradient
                                for j = start:size(Z,2)
                                    if (abs(grad(j) - a) < 1e-15) % grad(i) == a for floating pt numbers
                                        pts = [pts j];
                                    elseif (size(pts, 2) < sizeLine && size(pts, 2) > 0)
                                        pts = [];
                                    end
                                    %end after n (sizeline) pts
                                    if (size(pts,2) == sizeLine)
                                        break
                                    end
                                end
            
                                %find an uphill segment
                                for i = j:size(Z,2)
                                    if (grad(i) >= 0)
                                        count = count + 1;
                                    elseif (count < sizeLine/5 && count > 0)
                                        %size of 'hill' not big enough, reset
                                        count = 0; 
                                    end
                                    %end after n pts
                                    if (count == sizeLine/5)
                                        break
                                    end
                                end
                                
                                % get points for 2nd line segment
                                %sweep left of the uphill peak
                                for j = i:size(Z,2)
                                    if (abs(grad(j) - a) < 1e-15)
                                        pts2 = [pts2 j];
                                    elseif (size(pts2, 2) < sizeLine && size(pts2, 2) > 0)
                                        pts2 = [];
                                    end
                                    %end after n pts
                                    if (size(pts2,2) == sizeLine)
                                        break
                                    end
                                end
            
                                   %% some error checking?
                                if (isempty(pts))
                                     %means that the peak was far right -> just set p1 as a dummy
                        %             p1 = [0 1];
                                else
                                    p1 = polyfit([pts(1), pts(size(pts,2))], [Z(x,pts(1)), Z(x,pts(size(pts,2)))], 1);
                                end
            
                                if (isempty(pts2)) 
                                    %peak too low? too far left
                        %             p2 = [0 1]; % no difference if i comment/uncomment this out
                                else
                                    p2 = polyfit([pts2(1), pts2(size(pts2,2))], [Z(x,pts2(1)), Z(x,pts2(size(pts2,2)))], 1);
                                end
                                
                                %offsets
                                offset.difference(x,m) = - p1(2) + p2(2);
                                offset.p1(x,m) = p1(2);
                                offset.p2(x,m) = p2(2);
                                
                                %gradients
                                offset.m1(x,m) = p1(1);
                                offset.m2(x,m) = p2(1);
                                
                                %check if whole row was a padded 0
                                if (isempty(i)) i = -1; end
                                offset.peakPosition(x,m) = i;
                        end
                        app.Offset = offset;
            end

      
        end
        
        function appPlotBlocks(app, Z)
            
           %plot stuff
           ax1 = app.UIAxes6;
           ax2 = app.UIAxes7;
           plot(ax1, Z')
           plot(ax2, Z(1,:)) 
           ylim(ax2,[min(min(Z)),max(max(Z))]);
%            xlim(ax2,[min(min(Z)),max(max(Z))]);

           ylabel('FG_ST (0.02 V)','Interpreter', 'none','Parent', ax1)
           xlabel('G_G1 (0.2 V)','Interpreter', 'none','Parent', ax1)
           ylabel('FG_ST (0.02 V)','Interpreter', 'none','Parent', ax2)
           xlabel('G_G1 (0.2 V)','Interpreter', 'none','Parent', ax2)
           
           app.Slider.Limits = [1 size(app.Matrix.Matrix1{app.MatrixShift},1)];
           
           %update stuff in line table
           m = app.CurrentLine;
           offset = app.Offset;
           lines = [1:size(offset.p1, 1)]';
           tableData = [lines,offset.difference(:,m),offset.p1(:,m), offset.p2(:,m), offset.peakPosition(:,m)];
           app.UITable3.Data = tableData;
           app.UITable3.ColumnName = {'Row','Offset', 'Y-Intercept 1', 'Y-Intercept 2', 'Detected Position'};
           app.UITable3.ColumnWidth = {60 'auto'};
           
           
          %calculate sd and mean and whatever
          offsetSD = std(tableData(:,2:end));
          offsetMean = mean(tableData(:,2:end));
          title = {'Mean'; 'Standard Deviation'};
          data = [title [num2cell(offsetMean);num2cell(offsetSD)]];
          app.UITable4.Data = data;
          app.UITable4.ColumnName = {'','Offset', 'Y-Intercept 1', 'Y-Intercept 2', 'Detected Position'};
          app.UITable4.ColumnWidth = {60 'auto'};

           %table(lines,offset.difference(:,m),offset.p1(:,m), offset.p2(:,m), ...
%                'VariableNames',{'Row','Offset', 'Y_Intercept_1', 'Y_Intercept_2'});

            %plot the extracted lines overlay
            row2plot = app.CurrentLine;
            off = app.Offset;

            hold(ax2, 'on');
            fplot(ax2, @(x) off.p1(1, row2plot)+ off.m1(1, row2plot)*(x), [0 100], 'b.')
            fplot(ax2, @(x) off.p2(1, row2plot)+ off.m2(1, row2plot)*(x), [0 100], 'r.')
            hold(ax2, 'off');

        end
    
        
%         %capacitance matrix
        function updateMainCapMatrix(app)
            %main titles for gates
%             title = {'TG';'G1';'G2';'Q1';'Q2';'S'};
%             app.CapMatrix.ColumnNa me = ['' title'];
%isempty(app.Capacitance{app.MatrixShift}) &&
            if  ~isempty(app.Lines{app.MatrixShift})
                LineData = app.UITable5.Data;
                
                %Delta Q = 1 electron charge
                DeltaQ = 1.60217662e-19;
                
                %chargine energy of Dot: assume 5 meV
                Edot = app.DOTChargingEnergymeVEditField.Value * 1e-3; %eV
                Cdot = DeltaQ/Edot; % e/eV
                
                %charging energy of SET: assume 20meV
                Eset = app.SETChargingEnergymeVEditField.Value * 1e-3;
                Cset = DeltaQ/Eset;
    
                %Capacitance between TG and SET
                Ctgs = app.SETLeverArmEditField.Value * 1e-2 *Cset;
                
                %capacitance between dot and SET
                offset = app.Offset;
                DeltaV = mean(mean(offset.difference)) * 0.02; %the average of all the offset
                app.VtgsLabel.Text = sprintf('ΔVtgs = %d', DeltaV); 
                Cds = DeltaV/Edot*Ctgs;
                
                %capacitance g1 and d
                DeltaV1d = mean(gradient(LineData(:,4)));
                Cg1d = DeltaQ/DeltaV1d*5;
                app.Vg1Label.Text = sprintf('ΔVg1 = %d', DeltaV1d); 
                app.Vg1Label_2.Text = sprintf(' = %d', DeltaV1d*0.2); 
                
                %capacitance g2 and dot
                DeltaV2d = mean(gradient(LineData(:,3)));
                Cg2d = DeltaQ/DeltaV2d*5;
                app.Vg2Label.Text = sprintf('ΔVg2 = %d', DeltaV2d); 
                app.Vg2Label_2.Text = sprintf(' = %d', DeltaV2d*0.2); 

                %Capactitance g2 and set
                Cg2s = 1e-19;
                
                %capacitance g1 and set
                m = 0.0010; %gradient of the TG map
                Cg1s = m*Ctgs;
                
                %capacitance to infinity - Dont need!
%                 Cd = Cdot - Cds - Cg1d - Cg1s;
%                 Cs = Cset - Cds - Cg1s - Cg2s - Ctgs;
                
                %Table Column data
                DOT = [Cdot, -Cds, -Cg1d, -Cg2d,0];
                SET = [-Cds, Cset, -Cg1s, -Cg2s, -Ctgs];
                G1 = [-Cg1d, -Cg1s, 0, 0, 0];
                G2 = [-Cg2d, -Cg2s, 0, 0, 0];
                TG = [0, -Ctgs, 0, 0, 0];
                app.Capacitance{app.MatrixShift} = 1e+18*[DOT', SET', G1', G2', TG'];
            end
            app.CapMatrix.Data = app.Capacitance{app.MatrixShift};
%            app.UITable5.ColumnName = {'Row','Offset', 'Y-Intercept 1', 'Y-Intercept 2', 'Detected Position'};
            app.CapMatrix.ColumnWidth = {'auto'};
            
        end

        function results = updateFullLineTab(app)
            i = app.MatrixShift;
            
            M = getMatrixfromBox(app);
            appPlotMatrix(app,M ,app.UIAxMatrix, '', 0)
            
            app.XSlider.Limits = [1 size(M,1)];
            app.YSlider.Limits = [1 size(M,2)];
            
            app.XSlider.Value = 1;
            app.YSlider.Value = 1;

            ax1 = app.XSlice;
            plot(ax1, M(1,:)) 
            xlabel('G_G1 (0.2 V)','Interpreter', 'none','Parent', ax1)
            ylabel([app.MatrixDropDown.Value, '(0.2 V)'],'Interpreter', 'none','Parent', ax1)
            title(['Line across ' app.MatrixDropDown.Value],'Interpreter', 'none','Parent', ax1)
            
            ax2 = app.YSlice;
            plot(ax2, M(:,1)) 
            xlabel('G_G2 (0.2 V)','Interpreter', 'none','Parent', ax2)
            ylabel([app.MatrixDropDown.Value, '(5 A)'],'Interpreter', 'none','Parent', ax2)
            title(['Line down ' app.MatrixDropDown.Value],'Interpreter', 'none','Parent', ax2)

            
            %set axis ticks
            ax2.XTick = 0: size(M,1)/5: size(M,1); 
            ax1.XTick = 0: size(M,2)/8: size(M,2);
            ax1.XTickLabel = app.MatrixSize.xmin: (app.MatrixSize.xmax - app.MatrixSize.xmin)/8 : app.MatrixSize.xmax;
            ax2.XTickLabel = app.MatrixSize.ymin: (app.MatrixSize.ymax - app.MatrixSize.ymin)/8 : app.MatrixSize.ymax;
        end
        
        function results = getMatrixfromBox(app)
            M = app.MatrixDropDown.Value;
            i = app.MatrixShift;
            switch M
                case 'S_I'
                    results =  app.Matrix.Matrix1{i};
                case 'D_I'
                    results = app.Matrix.Matrix2{i};
                case 'FG_ST'
                    results = app.Matrix.Matrix3{i};
                case 'control_feedback_error'
                    results = app.Matrix.Matrix4{i};
            end
        end
        
        function results = loadData(app, filename)
            if isfile(['.\savedata\'  filename(1:end-4) '_GUI.mat'])
                % File exists.
                
                data = load(['.\savedata\' filename(1:end-4) '_GUI.mat']);
                
%                 app = data.app;
                
                app.File = data.File;
                app.Path = data.Path;
                app.Obj = data.Obj;
                app.LineEquations = data.LineEquations;
                app.Offset = data.Offset;
                app.MatrixShift = data.MatrixShift; 
                app.MaxShift = data.MaxShift;
                app.Matrix = data.Matrix;
                app.MatrixSize  = data.MatrixSize;
                app.Im = data.Im;
                app.Lines = data.Lines; %cell array holding the lines
                app.numLines  = data.numLines;
                app.LinePlotId = data.LinePlotId;
                app.BlkMCell = data.BlkMCell ;
                app.CurrentLine = data.CurrentLine ; 
                app.Capacitance = data.Capacitance ;
                app.LineTableData = data.LineTableData ;

                appPlotAllM(app)
                appPlotBlocks(app, app.BlkMCell{1})
                updateFullLineTab(app)
                updateMainLineTable(app, app.UITable5);
                updateMainCapMatrix(app)
                appPlotMatrix(app, app.Im.Orig, app.UIAxes5, '1. original', 1) 
                
                results = 1;
            else
                 % File does not exist.
                 results = 0;
            end
        end
        
        function resetGUI(app)
            app.Matrix.Matrix1 = [];
            app.Matrix.Matrix2 = [];
            app.Matrix.Matrix3 = [];
            app.Matrix.Matrix4 = [];
            app.Lines = [];
            app.Capacitance = [];
            app.LineTableData = [];
            
            app.UITable5.Data = [];
            app.CapMatrix.Data = [];
            
            app.MatrixShift = app.MaxShift;
            app.ShiftAmountSpinner.Value = 0;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            file = 'EDSR_2752_2018.08.13.13.35.34_G1vsG2.mat';    
%             file = 'meas.mat'
            app.File = file;
            app.Path = path;
            
            app.DropDown.Items{end + 1} = file;
            app.DropDown.Value = file;
                        
            
            addpath('tools');
            addpath(genpath('Matlabfns'));

            
            %initialise tab Tags
            app.S_ITab.Tag = 'displayM2';
            app.D_ITab.Tag = 'displayM1';
            app.FG_STTab.Tag = 'displayM3';
            app.control_feedback_errorTab.Tag = 'displayM4';        
            
%             sld = uislider(app.UIFigure,'Value',50);
%             limits = sld.Limits
%             sld.Limits = [-50 50];
%             sld.Value = 35;
%           

            %try to load existing data
            if (~loadData(app, file))
                %initialise everything. might take a while
                updateAll(app)
            end
            
%             jscrollpane = findjobj(mtable);
% jtable = jscrollpane.getViewport.getView;
%  
% % Now turn the JIDE sorting on
% jtable.setSortable(true);		% or: set(jtable,'Sortable','on');
% jtable.setAutoResort(true);
% jtable.setMultiColumnSortable(true);
% jtable.setPreserveSelectionsAfterSorting(true);
% 
        app.UIFigure.AutoResizeChildren = 'on';
        app.TabGroup.AutoResizeChildren = 'on';

        end

        % Button pushed function: OpenFileButtontAs
        function OpenFileButtontAsPushed(app, event)
           
            
            [file, path] = uigetfile('*.mat');
            

            %prevent app from automatically minimizing: 
            %https://au.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            
            app.File = file;
            app.Path = path;
            app.DropDown.Items{end + 1} = file;
            app.DropDown.Value = file;
            
            resetGUI(app);
            %try to load existing data
            if (~loadData(app, file))
                %initialise everything. might take a while
                updateAll(app)
            end
%             app.Obj = appExtractStuff(file, -4);
   
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            file = app.DropDown.Value;
%             app.DropDown.Items{end + 1} = num2str(value);
%             app.Obj = appExtractStuff(file, -4);
            app.File = file;
            
            resetGUI(app);
            %try to load existing data
            if (~loadData(app, file))
                %initialise everything. might take a while
                updateAll(app)
            end
            app.ShowLinesCheckBox.Value = 0;
            
  
        end

        % Value changed function: ShowLinesCheckBox
        function ShowLinesCheckBoxValueChanged(app, event)
            value = app.ShowLinesCheckBox.Value;
            if (value)
                plotLines(app, app.UIAxes1)
                plotLines(app, app.UIAxes2)
                plotLines(app, app.UIAxes3)
                plotLines(app, app.UIAxes4)
            else
                hideLines(app, app.UIAxes1)
            end
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            switch value
                case 0
                    appPlotMatrix(app, app.Im.Orig, app.UIAxes5, '1. original', 1)
                case 1
                    appPlotMatrix(app, app.Im.E, app.UIAxes5, '2. edge', 1)
                case 2
                    appPlotMatrix(app, app.Im.Dilated, app.UIAxes5, '3. dilated', 1)
                case 3
                    appPlotMatrix(app, app.Im.Thined, app.UIAxes5, '4. smoothened', 1)
                case 4 
                    appPlotMatrix(app, app.Im.Filtered, app.UIAxes5, '5. small remove', 1)
                case 5
                    appPlotMatrix(app, app.Im.Filtered2, app.UIAxes5, '6. labelededgeim', 1)
                case 6
                    appPlotMatrix(app, app.Im.Label, app.UIAxes5, '6. labelededgeim', 1)
                case 7
                    appPlotMatrix(app, app.Im.Dilated2, app.UIAxes5, '3. dilated', 1)
                case 8
                    appPlotMatrix(app, app.Im.Thined2, app.UIAxes5, '4. smoothened', 1)
            end

        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            %~~this function doesnt actually do anything ~~
            
            
            %check if showlines box is checked
            if (~app.ShowLinesCheckBox.Value) return; end %not checked, break out
            
            
            selectedTab = app.TabGroup2.SelectedTab.Tag;
            switch selectedTab
                case 'displayM1'
%                     plotLines(app, app.UIAxes1)
                case 'displayM2'
%                     plotLines(app, app.UIAxes2)
                case 'displayM3'
%                     plotLines(app, app.UIAxes3)
                case 'displayM4'
%                     plotLines(app, app.UIAxes4)
           end
        end

        % Value changed function: ShiftAmountSpinner
        function ShiftAmountSpinnerValueChanged(app, event)
            shiftAmt = app.ShiftAmountSpinner.Value;
            i = app.MaxShift;
            newAmt = shiftAmt + i;
            app.MatrixShift = shiftAmt + i;
           
%             app.Matrix.Matrix1 = appShiftMatrix(app, app.Matrix.Matrix1{5}, app.MatrixShift);
%             app.Matrix.Matrix2 = appShiftMatrix(app, app.Matrix.Matrix2{5}, app.MatrixShift);
%             app.Matrix.Matrix3 = appShiftMatrix(app, app.Matrix.Matrix3{5}, app.MatrixShift);
%             app.Matrix.Matrix4 = appShiftMatrix(app, app.Matrix.Matrix4{5}, app.MatrixShift);
            
            %check if any arbitrary (they should all be the same) matrix with new shift is already generated
            if isempty(app.Matrix.Matrix1{newAmt}) 
                % Make new matrices by shifting original
                app.Matrix.Matrix1{newAmt}  = shiftMatrix( app.Matrix.Matrix1{i} , 1*shiftAmt*(-1).^(1:size( app.Matrix.Matrix1{i} ,1)));
                app.Matrix.Matrix2{newAmt}  = shiftMatrix( app.Matrix.Matrix2{i} , 1*shiftAmt*(-1).^(1:size( app.Matrix.Matrix2{i} ,1)));
                app.Matrix.Matrix3{newAmt}  = shiftMatrix( app.Matrix.Matrix3{i} , 1*shiftAmt*(-1).^(1:size( app.Matrix.Matrix3{i} ,1)));
                app.Matrix.Matrix4{newAmt}  = shiftMatrix( app.Matrix.Matrix4{i} , 1*shiftAmt*(-1).^(1:size( app.Matrix.Matrix4{i} ,1)));

                
                %generate new lines
                
         
                %check if showlines checkbox is ticked

            end
            %Update all the graphs
            appPlotAllM(app)  
            if app.ShowLinesCheckBox.Value 
                % lines already there, need to remove old and plot new
                hideLines(app)
                plotLines(app, app.UIAxes1)
                plotLines(app, app.UIAxes2)
                plotLines(app, app.UIAxes3)
                plotLines(app, app.UIAxes4) 
            end 
            updateMainLineTable(app);
            updateMainCapMatrix(app);

            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = round(event.Value);
            row2plot = app.CurrentLine;
            Z = app.BlkMCell{row2plot};

            ax2 = app.UIAxes7;
            plot(ax2, Z(changingValue,:)) 
%             ylim(ax2,[min(min(Z)),max(max(Z))]);
            
            %plot the extracted thingymabob lines
            hold(ax2, 'on');
            off = app.Offset;
            fplot(ax2, @(x) off.p1(changingValue, row2plot)+ off.m1(changingValue, row2plot)*(x), [0 100], 'b.')
            fplot(ax2, @(x) off.p2(changingValue, row2plot)+ off.m2(changingValue, row2plot)*(x), [0 100], 'r.')
            hold(ax2, 'off');

        end

        % Value changed function: LinesDropDown
        function LinesDropDownValueChanged(app, event)
            value = app.LinesDropDown.Value;
            
            %extract number from string
            row2plot = sscanf(value, 'Line %d');
            app.CurrentLine = row2plot;
            appPlotBlocks(app, app.BlkMCell{row2plot})
            
            %plot the extracted thingymabob lines
%             ax2 = app.UIAxes7;
%             hold(ax2, 'on');
%             off = app.Offset;
%             fplot(ax2, @(x) off.p1(changingValue, row2plot)+ off.m1(changingValue, row2plot)*(x), [0 100], 'b.')
%             fplot(ax2, @(x) off.p2(changingValue, row2plot)+ off.m2(changingValue, row2plot)*(x), [0 100], 'r.')
%             hold(ax2, 'off');
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab.Title;
            switch selectedTab
                case 'Segmented Lines'
                    %delete everything... cbs checking lol
                    app.LinesDropDown.Items = {};
                    
                    %fill the dropdown box with lines
                    for i = 1:app.numLines{app.MatrixShift}
                       app.LinesDropDown.Items{end+1} = sprintf('Line %d', i); 
                    end
                case 'Processes'
                    
            end
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValue = round(event.Value);
            
            M = getMatrixfromBox(app);

            ax2 = app.YSlice;
            plot(ax2, M(:,changingValue)) 
        end

        % Value changed function: MatrixDropDown
        function MatrixDropDownValueChanged(app, event)
            value = app.MatrixDropDown.Value;
            updateFullLineTab(app);
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            changingValue = round(event.Value);
            
            M = getMatrixfromBox(app);

            ax2 = app.XSlice;
            plot(ax2, M(changingValue,:)) 
        end

        % Button pushed function: NextLineButton
        function NextLineButtonPushed(app, event)
            value = app.LinesDropDown.Value;
            
            %extract number from string
            row2plot = sscanf(value, 'Line %d');
            if (row2plot ~= app.numLines{app.MatrixShift})
                nextRow = row2plot + 1;
                app.CurrentLine = nextRow;
                app.LinesDropDown.Value = sprintf('Line %d', nextRow);
                appPlotBlocks(app, app.BlkMCell{row2plot})
            end

        end

        % Button pushed function: PreviousLineButton
        function PreviousLineButtonPushed(app, event)
            value = app.LinesDropDown.Value;
            %extract number from string
            row2plot = sscanf(value, 'Line %d');
            if (row2plot ~= 1)
                prevRow = row2plot - 1;
                app.CurrentLine = prevRow;
                app.LinesDropDown.Value = sprintf('Line %d', prevRow);
                appPlotBlocks(app, app.BlkMCell{row2plot})
            end

        end

        % Button pushed function: GenerateLinesButton
        function GenerateLinesButtonPushed(app, event)
            f = waitbar(0.7, 'Generating Lines');
            generateLines(app)
            
            generateOffsets(app)
            
            waitbar(.5,f,'Loading your data');
            appPlotBlocks(app, app.BlkMCell{1})

            updateMainLineTable(app, app.UITable5);
            updateFullLineTab(app)
            
            updateMainCapMatrix(app);
            waitbar(.99,f,'Loading your data');

            close(f)
        end

        % Value changed function: SETChargingEnergymeVEditField
        function SETChargingEnergymeVEditFieldValueChanged(app, event)
            value = app.SETChargingEnergymeVEditField.Value;
            updateMainCapMatrix(app)
        end

        % Value changed function: DOTChargingEnergymeVEditField
        function DOTChargingEnergymeVEditFieldValueChanged(app, event)
            value = app.DOTChargingEnergymeVEditField.Value;
            updateMainCapMatrix(app)
        end

        % Value changed function: SETLeverArmEditField
        function SETLeverArmEditFieldValueChanged(app, event)
            value = app.SETLeverArmEditField.Value;
            updateMainCapMatrix(app)
        end

        % Button pushed function: SaveCurrentDataButton
        function SaveCurrentDataButtonPushed(app, event)
            Capdata = app.CapMatrix.Data;
            File = app.File;
            Path = app.Path;
            Obj = app.Obj;
            LineEquations = app.LineEquations;
            
            Offset = app.Offset ;
            MatrixShift = app.MatrixShift; % is nuetral = no shift
            MaxShift = app.MaxShift; %i.e. can shift up or down by x
            Matrix = app.Matrix;
            MatrixSize = app.MatrixSize ;
            Im = app.Im;
       
            Lines = app.Lines; %cell array holding the lines
            numLines = app.numLines; 
            LinePlotId = app.LinePlotId;
            
            BlkMCell = app.BlkMCell; % Description
            CurrentLine = app.CurrentLine;  % Description
            Capacitance = app.Capacitance; % Description
            LineTableData = app.LineTableData; % array for the main table
            
            
            mkdir savedata
            addpath(genpath('savedata'))
%             save('.\savedata\Capacitances.mat', 'Capdata');
%             save('.\savedata\appData.mat', '-mat');
%             save(['.\savedata\' app.File(1:end-4)], '-struct');
            save(['.\savedata\' app.File(1:end-4) '_GUI.mat'], '-mat');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Home';
            app.Label = uilabel(app.Tab1);
            app.Label.Text = '';
            app.DropDown = uidropdown(app.Tab1);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.OpenFileButtontAs = uibutton(app.Tab1, 'push');
            app.OpenFileButtontAs.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtontAsPushed, true);
            app.OpenFileButtontAs.Text = 'Open File';
            app.TabGroup2 = uitabgroup(app.Tab1);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.S_ITab = uitab(app.TabGroup2);
            app.S_ITab.Title = 'S_I';
            app.UIAxes2 = uiaxes(app.S_ITab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.D_ITab = uitab(app.TabGroup2);
            app.D_ITab.Title = 'D_I';
            app.UIAxes1 = uiaxes(app.D_ITab);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            app.FG_STTab = uitab(app.TabGroup2);
            app.FG_STTab.Title = 'FG_ST';
            app.UIAxes3 = uiaxes(app.FG_STTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.control_feedback_errorTab = uitab(app.TabGroup2);
            app.control_feedback_errorTab.Title = 'control_feedback_error';
            app.UIAxes4 = uiaxes(app.control_feedback_errorTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.CapMatrix = uitable(app.Tab1);
            app.CapMatrix.ColumnName = {'DOT'; 'SET'; 'G1'; 'G2'; 'Top Gate'};
            app.CapMatrix.RowName = {};
            app.ShowLinesCheckBox = uicheckbox(app.Tab1);
            app.ShowLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLinesCheckBoxValueChanged, true);
            app.ShowLinesCheckBox.Text = 'Show Lines';
            app.ShiftAmountSpinnerLabel = uilabel(app.Tab1);
            app.ShiftAmountSpinnerLabel.Text = 'Shift Amount';
            app.ShiftAmountSpinner = uispinner(app.Tab1);
            app.ShiftAmountSpinner.Limits = [-9 10];
            app.ShiftAmountSpinner.ValueDisplayFormat = '%.0f';
            app.ShiftAmountSpinner.ValueChangedFcn = createCallbackFcn(app, @ShiftAmountSpinnerValueChanged, true);
            app.UITable5 = uitable(app.Tab1);
            app.UITable5.ColumnName = {'Line'; 'Gradient'; 'Y-Intercept'; 'X-Intercept'};
            app.UITable5.RowName = {};
            app.GenerateLinesButton = uibutton(app.Tab1, 'push');
            app.GenerateLinesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateLinesButtonPushed, true);
            app.GenerateLinesButton.Text = 'Generate Lines';
            app.SETChargingEnergymeVEditFieldLabel = uilabel(app.Tab1);
            app.SETChargingEnergymeVEditFieldLabel.Text = 'SET Charging Energy (meV)';
            app.SETChargingEnergymeVEditField = uieditfield(app.Tab1, 'numeric');
            app.SETChargingEnergymeVEditField.ValueChangedFcn = createCallbackFcn(app, @SETChargingEnergymeVEditFieldValueChanged, true);
            app.SETChargingEnergymeVEditField.Value = 5;
            app.SETLeverArmLabel = uilabel(app.Tab1);
            app.SETLeverArmLabel.Text = 'SET Lever Arm (%)';
            app.SETLeverArmEditField = uieditfield(app.Tab1, 'numeric');
            app.SETLeverArmEditField.ValueChangedFcn = createCallbackFcn(app, @SETLeverArmEditFieldValueChanged, true);
            app.SETLeverArmEditField.Value = 20;
            app.DOTLeverArmLabel = uilabel(app.Tab1);
            app.DOTLeverArmLabel.Enable = 'off';
            app.DOTLeverArmLabel.Text = 'DOT Lever Arm (%)';
            app.DOTLeverArmEditField = uieditfield(app.Tab1, 'numeric');
            app.DOTLeverArmEditField.Editable = 'off';
            app.DOTLeverArmEditField.Enable = 'off';
            app.DOTLeverArmEditField.Value = 20;
            app.DOTChargingEnergymeVEditFieldLabel = uilabel(app.Tab1);
            app.DOTChargingEnergymeVEditFieldLabel.Text = 'DOT Charging Energy (meV)';
            app.DOTChargingEnergymeVEditField = uieditfield(app.Tab1, 'numeric');
            app.DOTChargingEnergymeVEditField.ValueChangedFcn = createCallbackFcn(app, @DOTChargingEnergymeVEditFieldValueChanged, true);
            app.DOTChargingEnergymeVEditField.Value = 20;
            app.CapacitanceMatrixaFLabel = uilabel(app.Tab1);
            app.CapacitanceMatrixaFLabel.Text = 'Capacitance Matrix (aF)';
            app.SaveCurrentDataButton = uibutton(app.Tab1, 'push');
            app.SaveCurrentDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentDataButtonPushed, true);
            app.SaveCurrentDataButton.Text = 'Save Current Data';
            app.Vg1Label = uilabel(app.Tab1);
            app.Vg1Label.Text = 'Δ Vg1';
            app.Vg2Label = uilabel(app.Tab1);
            app.Vg2Label.Text = 'Δ Vg2';
            app.Vg2Label_2 = uilabel(app.Tab1);
            app.Vg2Label_2.Text = 'Δ Vg2';
            app.Vg1Label_2 = uilabel(app.Tab1);
            app.Vg1Label_2.Text = 'Δ Vg1';
            app.VtgsLabel = uilabel(app.Tab1);
            app.VtgsLabel.Text = 'Δ Vtgs';
            app.VLabel = uilabel(app.Tab1);
            app.VLabel.Text = '(0.2V)';
            app.VLabel_2 = uilabel(app.Tab1);
            app.VLabel_2.Text = '(V)';
            app.VLabel_3 = uilabel(app.Tab1);
            app.VLabel_3.Text = '(V)';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Segmented Lines';
            app.LinesDropDownLabel = uilabel(app.Tab2);
            app.LinesDropDownLabel.Text = 'Lines';
            app.LinesDropDown = uidropdown(app.Tab2);
            app.LinesDropDown.Items = {'Line 1', 'Line 2', 'Line 3'};
            app.LinesDropDown.ValueChangedFcn = createCallbackFcn(app, @LinesDropDownValueChanged, true);
            app.LinesDropDown.Value = 'Line 1';
            app.UIAxes6 = uiaxes(app.Tab2);
            title(app.UIAxes6, 'Section of lines across FG\_ST')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.UIAxes7 = uiaxes(app.Tab2);
            title(app.UIAxes7, 'Section of line across FG\_ST')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.UITable3 = uitable(app.Tab2);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.Label_4 = uilabel(app.Tab2);
            app.Label_4.Text = '';
            app.Slider = uislider(app.Tab2);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.UITable4 = uitable(app.Tab2);
            app.UITable4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable4.RowName = {};
            app.PreviousLineButton = uibutton(app.Tab2, 'push');
            app.PreviousLineButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousLineButtonPushed, true);
            app.PreviousLineButton.Text = 'Previous Line';
            app.NextLineButton = uibutton(app.Tab2, 'push');
            app.NextLineButton.ButtonPushedFcn = createCallbackFcn(app, @NextLineButtonPushed, true);
            app.NextLineButton.Text = 'Next Line';
            app.FullLinesTab = uitab(app.TabGroup);
            app.FullLinesTab.Title = 'Full Lines';
            app.UIAxMatrix = uiaxes(app.FullLinesTab);
            title(app.UIAxMatrix, 'Title')
            xlabel(app.UIAxMatrix, 'X')
            ylabel(app.UIAxMatrix, 'Y')
            app.XSlice = uiaxes(app.FullLinesTab);
            title(app.XSlice, 'Title')
            xlabel(app.XSlice, 'X')
            ylabel(app.XSlice, 'Y')
            app.YSlice = uiaxes(app.FullLinesTab);
            title(app.YSlice, 'Title')
            xlabel(app.YSlice, 'X')
            ylabel(app.YSlice, 'Y')
            app.Label_5 = uilabel(app.FullLinesTab);
            app.Label_5.Text = '';
            app.YSlider = uislider(app.FullLinesTab);
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.Label_6 = uilabel(app.FullLinesTab);
            app.Label_6.Text = '';
            app.XSlider = uislider(app.FullLinesTab);
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.MatrixDropDownLabel = uilabel(app.FullLinesTab);
            app.MatrixDropDownLabel.Text = 'Matrix';
            app.MatrixDropDown = uidropdown(app.FullLinesTab);
            app.MatrixDropDown.Items = {'S_I', 'D_I', 'FG_ST', 'control_feedback_error'};
            app.MatrixDropDown.ValueChangedFcn = createCallbackFcn(app, @MatrixDropDownValueChanged, true);
            app.MatrixDropDown.Value = 'S_I';
            app.ProcessesTab = uitab(app.TabGroup);
            app.ProcessesTab.Title = 'Processes';
            app.UIAxes5 = uiaxes(app.ProcessesTab);
            app.Label_3 = uilabel(app.ProcessesTab);
            app.Label_3.Text = '';
            app.Spinner = uispinner(app.ProcessesTab);
            app.Spinner.Limits = [0 10];
            app.Spinner.ValueDisplayFormat = '%.0f';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ToRThing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end